classdef AppCurrentVersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TurnGauge                 matlab.ui.control.SemicircularGauge
        MultiplayerModeLabel      matlab.ui.control.Label
        FlamingSunDropDown        matlab.ui.control.DropDown
        FlamingSunDropDownLabel   matlab.ui.control.Label
        TurnLabel                 matlab.ui.control.Label
        OpponentsScoreGauge       matlab.ui.control.LinearGauge
        OpponentsScoreGaugeLabel  matlab.ui.control.Label
        YourScoreGauge            matlab.ui.control.LinearGauge
        YourScoreGaugeLabel       matlab.ui.control.Label
        YourOpponentsDiceLabel    matlab.ui.control.Label
        Image_10                  matlab.ui.control.Image
        Image_9                   matlab.ui.control.Image
        Image_8                   matlab.ui.control.Image
        Image_7                   matlab.ui.control.Image
        Image_6                   matlab.ui.control.Image
        Image_5                   matlab.ui.control.Image
        Image_4                   matlab.ui.control.Image
        Image_3                   matlab.ui.control.Image
        Image_2                   matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        Gauge_5                   matlab.ui.control.Gauge
        Gauge_5Label              matlab.ui.control.Label
        Gauge_4                   matlab.ui.control.Gauge
        Gauge_4Label              matlab.ui.control.Label
        Gauge_3                   matlab.ui.control.Gauge
        Gauge_3Label              matlab.ui.control.Label
        Gauge_2                   matlab.ui.control.Gauge
        Gauge_2Label              matlab.ui.control.Label
        Gauge                     matlab.ui.control.Gauge
        GaugeLabel                matlab.ui.control.Label
        RollthediceButton         matlab.ui.control.Button
        LockValueButton_5         matlab.ui.control.StateButton
        LockValueButton_4         matlab.ui.control.StateButton
        LockValueButton_3         matlab.ui.control.StateButton
        LockValueButton_2         matlab.ui.control.StateButton
        LockValueButton           matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        dvals = zeros(10);
        yourScore;
        oppsScore;
        currentPlayerIsYou = true;  
        hasEnteredGameYou = false;
        hasEnteredGameOpp = false;
        isLocal;
        channelID = 2981350;
        writeAPIKey = 'UV6550WIPPNILEPR';
        readAPIKey = 'PL6XRA50AECFTUUE';
    end

    methods (Access = public)
        
        function updateDiceImages(app)
            app.Image.ImageSource = app.chooseImage(app.dvals(1));
            app.Image_2.ImageSource = app.chooseImage(app.dvals(2));
            app.Image_3.ImageSource = app.chooseImage(app.dvals(3));
            app.Image_4.ImageSource = app.chooseImage(app.dvals(4));
            app.Image_5.ImageSource = app.chooseImage(app.dvals(5));
        end


        function imgSource = chooseImage(~,dVal)
            folder = fullfile(fileparts(mfilename('fullpath')), "Images");
            switch dVal
                case 1
                    imgSource = fullfile(folder, "halfmoons.gif");
                case 2
                    imgSource = fullfile(folder, "pyramids.gif");
                case 3
                    imgSource = fullfile(folder, "starburst.gif");
                case 4
                    imgSource = fullfile(folder, "five.gif");
                case 5
                    imgSource = fullfile(folder, "stars.gif");
                case 6
                    imgSource = fullfile(folder, "ten.gif");
                case 6.5
                    imgSource = fullfile(folder, "flamingsun.gif");
            end
        end


        function updateScores(app,yourScore,oppsScore)
            app.YourScoreGauge.Value = yourScore;
            app.OpponentsScoreGauge.Value = oppsScore;
            app.yourScore = yourScore;
            app.oppsScore = oppsScore;
        end

        function calcScore(app)
            roundStartScore = app.yourScore;
            if (app.dvals(5) == 6.5) && app.FlamingSunDropDown.Visible
                app.waitingForFlamingSunSelection = true;
                return;
            end
            
           numVals = zeros(1,6);

            for i = 1:5
                val = app.dvals(i);
                if (val >= 1) && (val <=6)
                    numVals(val) = numVals(val) + 1;
                end
            end

            FlashScore = [20, 30, 40, 50, 60, 100];
            FreightTrainScore = [200, 300, 400, 500, 500, 0];
            
            j=1;
            while(j<=6)
                if(numVals(j) == 5) %FreightTrains
                    app.yourScore = app.yourScore + FreightTrainScore(j);
                    numVals(j) = 0;
                elseif(numVals(j) >= 3) %Flashes
                    app.yourScore = app.yourScore + FlashScore(j);
                    numVals(j) = numVals(j) - 3;
                end
                j=j+1;
            end
            
            app.yourScore = app.yourScore + numVals(4) * 5 + numVals(6) * 10;
            
            roundScore = app.yourScore - roundStartScore;            
            if roundScore > 0
                if app.currentPlayerIsYou
                    if ~app.hasEnteredGameYou
                        if roundScore >= 35
                            app.hasEnteredGameYou = true;
                            app.yourScore = app.yourScore + roundScore;
                        else
                            msgbox("You didn't enter the game yet! Need at least 35.");
                            return;
                        end
                    else
                        app.yourScore = app.yourScore + roundScore;
                    end
                else
                    if ~app.hasEnteredGameOpp
                        if roundScore >= 35
                            app.hasEnteredGameOpp = true;
                            app.oppsScore = app.oppsScore + roundScore;
                        else
                            msgbox("Opponent didn't enter the game yet! Need at least 35.");
                            return;
                        end
                    else
                        app.oppsScore = app.oppsScore + roundScore;
                    end
                end
                app.updateScores(app.yourScore, app.oppsScore);
            end

            if(app.hasEnteredGameYou == true)
                app.updateScores(app.yourScore,app.oppsScore)
            else
                app.yourScore = roundStartScore;
            end
        end
        
        function multMode(app)
            selection = uiconfirm(app.UIFigure, ...
                'Online Multiplayer or Local Multiplayer?', ...
                'Multiplayer Mode Selection', ...
                'Options', {'Online', 'Local'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2);
            
            if strcmp(selection, 'Local')
                app.isLocal = true;
                app.MultiplayerModeLabel.Text = app.MultiplayerModeLabel.Text + " Local";
                app.TurnGauge.Value = 1.5;
            else
                app.isLocal = false;
                app.MultiplayerModeLabel.Text = app.MultiplayerModeLabel.Text + " Online";
                
                selection = uiconfirm(app.UIFigure, ...
                    'Are you hosting a match, or joining a match?', ...
                    'Multiplayer Mode Selection', ...
                    'Options', {'Hosting', 'Joining'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 2);
                
                if strcmp(selection, 'Hosting')
                    thingSpeakWrite(app.channelID, 'Fields', 8, 'Values', 1, 'WriteKey', app.writeAPIKey);
                    msgbox('Hosted successfully!', 'Success');
                    app.TurnGauge.Value = 1.5;
                else
                    if(thingSpeakRead(app.channelID, 'Fields', 8, 'ReadKey', app.readAPIKey) == 1)
                        thingSpeakWrite(app.channelID, 'Fields', 8, 'Values', 2, 'WriteKey', app.writeAPIKey);
                        msgbox('Joined successfully!', 'Success');
                        app.TurnGauge.Value = 0.5;
                    else
                        msgbox('Failed to join, make sure the host is online and you have waited long enough for ThingSpeak to update before attempting to join.', 'Error');
                        app.multMode;
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.multMode;

            app.yourScore = 0;
            app.oppsScore = 0;
            app.TurnLabel.Text = "Your Turn";


        end

        % Button pushed function: RollthediceButton
        function RollthediceButtonPushed(app, event)
            if(app.LockValueButton.Value == false)
                app.dvals(1) = randi(6);
            end
            if(app.LockValueButton_2.Value == false)
                app.dvals(2) = randi(6);
            end
            if(app.LockValueButton_3.Value == false)
                app.dvals(3) = randi(6);
            end
            if(app.LockValueButton_4.Value == false)
                app.dvals(4) = randi(6);
            end
            if(app.LockValueButton_5.Value == false)
                app.dvals(5) = randi(6);
                if(app.dvals(5) == 3)
                    app.dvals(5) = 6.5;
                end
            end
            app.Gauge.Value = app.dvals(1);
            app.Gauge_2.Value = app.dvals(2);
            app.Gauge_3.Value = app.dvals(3);
            app.Gauge_4.Value = app.dvals(4);
            app.Gauge_5.Value = app.dvals(5); % instead of a 3 it has a wild
            
            app.updateDiceImages();
            app.calcScore();
            
            app.currentPlayerIsYou = ~app.currentPlayerIsYou;
            if app.currentPlayerIsYou
                app.TurnLabel.Text = "Your Turn";
            else
                app.TurnLabel.Text = "Opponent's Turn";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LockValueButton = uibutton(app.UIFigure, 'state');
            app.LockValueButton.Text = 'Lock Value';
            app.LockValueButton_2 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_2.Text = 'Lock Value';
            app.LockValueButton_3 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_3.Text = 'Lock Value';
            app.LockValueButton_4 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_4.Text = 'Lock Value';
            app.LockValueButton_5 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_5.Text = 'Lock Value';
            app.RollthediceButton = uibutton(app.UIFigure, 'push');
            app.RollthediceButton.ButtonPushedFcn = createCallbackFcn(app, @RollthediceButtonPushed, true);
            app.RollthediceButton.Text = 'Roll the dice!';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [1 6];
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = 'Gauge';
            app.Gauge_2 = uigauge(app.UIFigure, 'circular');
            app.Gauge_2.Limits = [1 6];
            app.Gauge_3Label = uilabel(app.UIFigure);
            app.Gauge_3Label.Text = 'Gauge';
            app.Gauge_3 = uigauge(app.UIFigure, 'circular');
            app.Gauge_3.Limits = [1 6];
            app.Gauge_4Label = uilabel(app.UIFigure);
            app.Gauge_4Label.Text = 'Gauge';
            app.Gauge_4 = uigauge(app.UIFigure, 'circular');
            app.Gauge_4.Limits = [1 6];
            app.Gauge_5Label = uilabel(app.UIFigure);
            app.Gauge_5Label.Text = 'Gauge';
            app.Gauge_5 = uigauge(app.UIFigure, 'circular');
            app.Gauge_5.Limits = [1 7];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_10 = uiimage(app.UIFigure);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.YourOpponentsDiceLabel = uilabel(app.UIFigure);
            app.YourOpponentsDiceLabel.Text = 'Your Opponent''s Dice';
            app.YourScoreGaugeLabel = uilabel(app.UIFigure);
            app.YourScoreGaugeLabel.Text = 'Your Score';
            app.YourScoreGauge = uigauge(app.UIFigure, 'linear');
            app.YourScoreGauge.Limits = [0 500];
            app.OpponentsScoreGaugeLabel = uilabel(app.UIFigure);
            app.OpponentsScoreGaugeLabel.Text = 'Opponent''s Score';
            app.OpponentsScoreGauge = uigauge(app.UIFigure, 'linear');
            app.OpponentsScoreGauge.Limits = [0 500];
            app.TurnLabel = uilabel(app.UIFigure);
            app.TurnLabel.Text = 'Your turn';
            app.FlamingSunDropDownLabel = uilabel(app.UIFigure);
            app.FlamingSunDropDownLabel.Visible = 'off';
            app.FlamingSunDropDownLabel.Text = 'Flaming Sun';
            app.FlamingSunDropDown = uidropdown(app.UIFigure);
            app.FlamingSunDropDown.Items = {'None', 'Moons', 'Triangles', 'Bolts', '5', 'Stars', '10'};
            app.FlamingSunDropDown.Visible = 'off';
            app.FlamingSunDropDown.Value = 'None';
            app.MultiplayerModeLabel = uilabel(app.UIFigure);
            app.MultiplayerModeLabel.Text = 'Multiplayer Mode:';
            app.TurnGauge = uigauge(app.UIFigure, 'semicircular');
            app.TurnGauge.Limits = [0 2];
            app.TurnGauge.MajorTicks = [0 0.5 1 1.5 2];
            app.TurnGauge.MajorTickLabels = {' ', ' Their Turn', ' ', ' Your Turn', ' '};
            app.TurnGauge.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppCurrentVersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end