classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LockValueButton_10        matlab.ui.control.StateButton
        LockValueButton_9         matlab.ui.control.StateButton
        LockValueButton_8         matlab.ui.control.StateButton
        LockValueButton_7         matlab.ui.control.StateButton
        LockValueButton_6         matlab.ui.control.StateButton
        EndyourTurnButton         matlab.ui.control.Button
        FetchingUpdatefromThingspeakinLabel  matlab.ui.control.Label
        RollthediceButton_2       matlab.ui.control.Button
        YourDiceLabel             matlab.ui.control.Label
        TurnGauge                 matlab.ui.control.SemicircularGauge
        MultiplayerModeLabel      matlab.ui.control.Label
        FlamingSunDropDown        matlab.ui.control.DropDown
        FlamingSunDropDownLabel   matlab.ui.control.Label
        TurnLabel                 matlab.ui.control.Label
        OpponentsScoreGauge       matlab.ui.control.LinearGauge
        OpponentsScoreGaugeLabel  matlab.ui.control.Label
        YourScoreGauge            matlab.ui.control.LinearGauge
        YourScoreGaugeLabel       matlab.ui.control.Label
        YourOpponentsDiceLabel    matlab.ui.control.Label
        Image_10                  matlab.ui.control.Image
        Image_9                   matlab.ui.control.Image
        Image_8                   matlab.ui.control.Image
        Image_7                   matlab.ui.control.Image
        Image_6                   matlab.ui.control.Image
        Image_5                   matlab.ui.control.Image
        Image_4                   matlab.ui.control.Image
        Image_3                   matlab.ui.control.Image
        Image_2                   matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        Gauge_5                   matlab.ui.control.Gauge
        Gauge_5Label              matlab.ui.control.Label
        Gauge_4                   matlab.ui.control.Gauge
        Gauge_4Label              matlab.ui.control.Label
        Gauge_3                   matlab.ui.control.Gauge
        Gauge_3Label              matlab.ui.control.Label
        Gauge_2                   matlab.ui.control.Gauge
        Gauge_2Label              matlab.ui.control.Label
        Gauge                     matlab.ui.control.Gauge
        GaugeLabel                matlab.ui.control.Label
        RollthediceButton         matlab.ui.control.Button
        LockValueButton_5         matlab.ui.control.StateButton
        LockValueButton_4         matlab.ui.control.StateButton
        LockValueButton_3         matlab.ui.control.StateButton
        LockValueButton_2         matlab.ui.control.StateButton
        LockValueButton           matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        dvals = zeros(10);
        scores = zeros(2);
        currentPlayer = 0;
        enteredGame = false(1,4);
        isLocal;
        channelID = 2981350;
        playerNum;
        writeAPIKey = 'UV6550WIPPNILEPR';
        readAPIKey = 'PL6XRA50AECFTUUE';
        currentTick;
        tickTimer;
        numVals = zeros(6);
        completedFirstRoll = false;
        thingSpeakTimer = 0;
        thingSpeakRequested = false;

        currentTurn = 0;
    end
    
    properties (Access = public)
       waitForFSSelect = false;
    end

    methods (Access = public)
        
        function updateDiceImages(app)
            app.Image.ImageSource = app.chooseImage(app.dvals(1));
            app.Image_2.ImageSource = app.chooseImage(app.dvals(2));
            app.Image_3.ImageSource = app.chooseImage(app.dvals(3));
            app.Image_4.ImageSource = app.chooseImage(app.dvals(4));
            app.Image_5.ImageSource = app.chooseImage(app.dvals(5));
            app.Image_6.ImageSource = app.chooseImage(app.dvals(6));
            app.Image_7.ImageSource = app.chooseImage(app.dvals(7));
            app.Image_8.ImageSource = app.chooseImage(app.dvals(8));
            app.Image_9.ImageSource = app.chooseImage(app.dvals(9));
            app.Image_10.ImageSource = app.chooseImage(app.dvals(10));
        end


        function imgSource = chooseImage(~,dVal)
            folder = fullfile(fileparts(mfilename('fullpath')), "Images");
            switch dVal
                case 1
                    imgSource = fullfile(folder, "halfmoons.gif");
                case 2
                    imgSource = fullfile(folder, "pyramids.gif");
                case 3
                    imgSource = fullfile(folder, "starburst.gif");
                case 4
                    imgSource = fullfile(folder, "five.gif");
                case 5
                    imgSource = fullfile(folder, "stars.gif");
                case 6
                    imgSource = fullfile(folder, "ten.gif");
                case 6.5
                    imgSource = fullfile(folder, "flamingsun.gif");
                otherwise
                    imgSource = fullfile(folder, "halfmoons.gif");
            end
        end


        function updateScoresLocal(app,score)
            if(app.currentPlayer == 1)
                app.YourScoreGauge.Value = score;
                app.scores(app.currentPlayer) = score;
            elseif(app.currentPlayer == 2)
                app.OpponentsScoreGauge.Value = score;
                app.scores(app.currentPlayer) = score;
            end
        end


        function roundScore = calcScore(app)
            if(app.completedFirstRoll == false)
                roundScore = 0;
                return
            end
            if(app.currentPlayer == 0)
                roundScore = 0;
                return
            end
            disp("Starting to Calculate Score...");
            roundStartScore = app.scores(app.currentPlayer);
            roundEndScore = roundStartScore;
            
            if (app.dvals(5) == 6.5) && app.FlamingSunDropDown.Visible
                return;
            end

            app.numVals = zeros(1,6);
            for i = 1:5
                val = app.dvals(i + ((app.currentPlayer - 1) * 5 ));
                if (val >= 1) && (val <=6)
                    app.numVals(val) = app.numVals(val) + 1;
                end
            end

            if(app.dvals(app.currentPlayer * 5) == 6.5)
                app.FlamingSunDropDown.Visible = true;                
                app.FlamingSunDropDown.Items = app.getPossibleFSSelects(app.numVals);
                prevPlayer = app.currentPlayer;
                app.currentPlayer = 0;
                app.rollDiceButtonVis;
                uialert(app.UIFigure, "You rolled a flaming sun! This can be used as a wild card, use the dropdown menu to choose what to do with it!" + newline + "If you rolled two of the same, you must use the Flaming Sun to make three of the same.", "Flaming sun selection", "Icon","info");
                disp("Beginning to wait");
                
                app.waitForFSSelect = true;
                
                waitfor(app,"waitForFSSelect",false);
                app.currentPlayer = prevPlayer;
                app.rollDiceButtonVis;
                disp("Finished waiting");
                
                temp = app.FSSelectToNum;
                disp(temp);
                app.dvals(app.currentPlayer * 5) = temp;
                app.updateDiceImages;
                app.numVals(temp) = app.numVals(temp) + 1;
                app.FlamingSunDropDown.Visible = false; 
            end

            FlashScore = [20, 30, 40, 50, 60, 100];
            FreightTrainScore = [200, 300, 400, 500, 500, 0];
            
            j=1;
            while(j<=6)
                if(app.numVals(j) == 5) %FreightTrains
                    roundEndScore = roundEndScore + FreightTrainScore(j);
                    app.numVals(j) = 0;
                elseif(app.numVals(j) >= 3) %Flashes
                    roundEndScore = roundEndScore + FlashScore(j);
                    app.numVals(j) = app.numVals(j) - 3;
                end
                j=j+1;
            end
            
            roundEndScore = roundEndScore + app.numVals(4) * 5 + app.numVals(6) * 10;

            roundScore = roundEndScore - roundStartScore;

            scoreToEnter = 35;
            if(app.enteredGame(app.currentPlayer) == false)
                if(roundScore >= scoreToEnter)
                    app.enteredGame(app.currentPlayer) = true;
                elseif(app.completedFirstRoll == true)
                    uialert(app.UIFigure,"You are currently not in the game; you need to score at least " + scoreToEnter + " points in one round to enter the game! You scored " + roundScore + " points this round. Roll again!", "Not enough points to enter!", "Icon","warning");
                    app.completedFirstRoll = false;
                end
            end
            if(app.enteredGame(app.currentPlayer) == true)
                app.EndyourTurnButton.Text = "End your Turn:" + newline + "Gain " + roundScore + " points!";
                disp("Updated potential Score display...");
            end

        end
        
        function multMode(app)
            selection = uiconfirm(app.UIFigure, ...
                'Online Multiplayer or Local Multiplayer?', ...
                'Multiplayer Mode Selection', ...
                'Options', {'Online', 'Local'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2);
            
            if strcmp(selection, 'Local')
                app.isLocal = true;
                app.MultiplayerModeLabel.Text = app.MultiplayerModeLabel.Text + " Local";
                app.currentPlayer = 1;
                app.playerNum = 1;
            else
                app.isLocal = false;
                app.FetchingUpdatefromThingspeakinLabel.Visible = true;
                app.MultiplayerModeLabel.Text = app.MultiplayerModeLabel.Text + " Online";
                
                selection = uiconfirm(app.UIFigure, ...
                    ['Are you hosting a match, or joining a match?' newline '(Errors can occur if you do not wait long enough for MATLAB Thingspeak, or if your system time is inaccurate.)'], ...
                    'Multiplayer Mode Selection', ...
                    'Options', {'Hosting', 'Joining'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 2);
                
                if strcmp(selection, 'Hosting')
                    if(thingSpeakRead(app.channelID, 'Fields', 8, 'ReadKey', app.readAPIKey) == 0)
                        thingSpeakWrite(app.channelID, 'Fields', 8, 'Values', 1, 'WriteKey', app.writeAPIKey);
                        uialert(app.UIFigure,'Hosted successfully!', 'Success', 'Icon','success');
                        app.playerNum = thingSpeakRead(app.channelID, 'Fields', 8, 'ReadKey', app.readAPIKey);
                        app.YourDiceLabel.Text = "Player " + app.playerNum + "'s Dice";
                        app.YourOpponentsDiceLabel.Text = "Player " + (3 - app.playerNum) + "'s Dice";

                        % Multiplayer: Clears ThinkSpeak channel
                        CreateNewGame();

                    else
                        uialert(app.UIFigure,'Failed to Host: Resetting the server state... please try again', 'Error');
                        thingSpeakWrite(app.channelID, 'Fields', 8, 'Values', 0, 'WriteKey', app.writeAPIKey);
                        app.multMode;
                    end
                else
                    if(thingSpeakRead(app.channelID, 'Fields', 8, 'ReadKey', app.readAPIKey) == 1)
                        thingSpeakWrite(app.channelID, 'Fields', 8, 'Values', 2, 'WriteKey', app.writeAPIKey);
                        uialert(app.UIFigure,'Joined successfully!', 'Success', 'Icon','success');
                        app.playerNum = thingSpeakRead(app.channelID, 'Fields', 8, 'ReadKey', app.readAPIKey);
                        app.YourDiceLabel.Text = "Player " + app.playerNum + "'s Dice";
                        app.YourOpponentsDiceLabel.Text = "Player " + (3 - app.playerNum) + "'s Dice";
                    else
                        uialert(app.UIFigure,'Failed to join, make sure the host is online and you have waited long enough for ThingSpeak to update before attempting to join. Make sure your system time is accurate!', 'Error');
                        app.multMode;
                    end
                end
            end
        end
        
        function repeatFunc(app)
            app.currentTick = app.currentTick + 1;
            app.currentTick = uint64(app.currentTick);
            app.rollDiceButtonVis;
            app.calcScore;
            disp(app.currentTick);
            t = app.getUpdateTimer(app.playerNum);

            if(app.isLocal == false && t == 0)
                app.updateThingspeakLabel
                app.fetchUpdate;
                if(mod(app.currentTick,20) == 0)
                    app.fetchUpdate;
                    app.sendUpdate;
                    disp("Updating Thingspeak");
                end
            end
            disp("The current player is: " + app.currentPlayer);
        end
        
        function updateThingspeakLabel(app,timer)
            if(timer==1)
                tempString = ",";
            else
                tempString = "s,";
            end
            app.FetchingUpdatefromThingspeakinLabel.Text = "Fetching and Sending ThingSpeak Update in " + timer + " Second" + tempString + " please wait...";
        end

        function rollDiceButtonVis(app)
            app.updateTurnGauge
            %{ 
                Player 1 Controls vis list:

                app.RollthediceButton.Visible = true;
                
            %}
            p1locks = [app.LockValueButton, app.LockValueButton_2, app.LockValueButton_3, app.LockValueButton_4, app.LockValueButton_5];
            p2locks = [app.LockValueButton_6, app.LockValueButton_7, app.LockValueButton_8, app.LockValueButton_9, app.LockValueButton_10];
            p1cons = [app.RollthediceButton, p1locks];
            p2cons = [app.RollthediceButton_2, p2locks];
            if(app.currentPlayer == 1)
                for i = 1:length(p1cons)
                    p1cons(i).Visible = true;
                end
                for i = 1:length(p2cons)
                    p2cons(i).Visible = false;
                end
                app.EndyourTurnButton.Visible = true;
            elseif(app.currentPlayer == 2)
                for i = 1:length(p1cons)
                    p1cons(i).Visible = false;
                end
                for i = 1:length(p2cons)
                    p2cons(i).Visible = true;
                end
                app.EndyourTurnButton.Visible = true;
            elseif(app.currentPlayer == 0)
                for i = 1:length(p1cons)
                    p1cons(i).Visible = false;
                end
                for i = 1:length(p2cons)
                    p2cons(i).Visible = false;
                end
                app.EndyourTurnButton.Visible = false;
            else
                disp("Current player is currently somehow neither 1 or 2");
            end
            if(app.completedFirstRoll == false)
                app.EndyourTurnButton.Visible = false;
                for i = 1:length(p1locks)
                    p1locks(i).Visible = false;
                end
                for i = 1:length(p2locks)
                    p2locks(i).Visible = false;
                end
                
            end
        end
        
        function fetchUpdate(app)
            % Convert number back to array of digits
            number = thingSpeakRead(app.channelID, 'Fields', (3 - app.playerNum), 'ReadKey', app.readAPIKey);
            parsedDigits = double(string(num2str(number))') - '0';
            disp(parsedDigits);
            
            app.dvals(6:10) = uint64(parsedDigits);

            response = ReadGameState();
            if (response(end).turnNumber - 1 == app.currentTurn)
                return
            elseif (response(end).turnNumber == app.currentTurn)
                app.dvals((app.currentPlayer - 1) * 5 +1 : (app.currentPlayer - 1)*5+1) = response.dice;
                app.scores(app.currentPlayer) = response(end).accumulatedScore;

                uialert(app.UIFigure,'The other player has completed their turn successfully: ' + response(end).turnScore + 'Points. Next player!', 'Success', 'Icon','success');
                
                app.currentPlayer = 3 - app.currentPlayer;
            end


        end

        function sendUpdate(app)
            disp([app.dvals(1:5),app.scores(1)]);
            disp(convertStringsToChars(num2str([app.dvals(1:5),app.scores(1)])));
            
            digitsArray = app.dvals(1:5);
            number = str2num(strjoin(string(digitsArray), ''));
            disp(number);

            thingSpeakWrite(app.channelID, 'Fields', (app.playerNum), 'Values', number, 'WriteKey', app.writeAPIKey);
        end
        
        function rollDice(app)
                folder = fullfile(fileparts(mfilename('fullpath')), "Sounds");
                filename = fullfile(folder, "diceSound1.mp3");
                [y, Fs] = audioread(filename);
                player = audioplayer(y, Fs);
                
                filename = fullfile(folder, "diceSound2.mp3");
                [y, Fs] = audioread(filename);
                player2 = audioplayer(y, Fs);
                
                filename = fullfile(folder, "diceSound3.mp3");
                [y, Fs] = audioread(filename);
                player3 = audioplayer(y, Fs);

                play(player);
                play(player2);
                play(player3);

            locks = [app.LockValueButton.Value,...
                    app.LockValueButton_2.Value,...
                    app.LockValueButton_3.Value,...
                    app.LockValueButton_4.Value,...
                    app.LockValueButton_5.Value,...
                    app.LockValueButton_6.Value,...
                    app.LockValueButton_7.Value,...
                    app.LockValueButton_8.Value,...
                    app.LockValueButton_9.Value,...
                    app.LockValueButton_10.Value];
            
            for j = 1:50
                for i = 1:5
                    if(locks(((app.currentPlayer - 1) * 5) + i) == false)
                        app.dvals(((app.currentPlayer - 1) * 5) + i) = randi(6);
                    end
                end
                if(app.dvals(((app.currentPlayer - 1) * 5) + 5) == 3)
                    app.dvals(((app.currentPlayer - 1) * 5) + 5) = 6.5; %Set dice 5 to flaming sun instead of pyramids like other dice
                end
                pause(0.01);
                app.updateDiceImages
            end
            
            app.completedFirstRoll = true;
            app.updateDiceImages;
            app.calcScore;
        end
        
        function updateTurnGauge(app)
            app.TurnGauge.Value = app.currentPlayer;
        end
        
        function results = getPossibleFSSelects(~, numVals)

            % switch check all values for first run and record pairs
            % then check wildcard and restrict to pairs available, 
            % can be a for an if or something else similar
            results = "MAKE A SELECTION!";
            only = true;
            if(numVals(1) == 2)
                results = [results, "Halfmoons (Twos)"];
                only = false;
            end
            if(numVals(2) == 2)
                results = [results, "Pyramids (Threes)"];
                only = false;
            end
            if(numVals(3) == 2)
                results = [results, "Starburst (Fours)"];
                only = false;
            end
            if(numVals(5) == 2)
                only = false;
            end
            if(numVals(4) == 2 || only == true)
                results = [results, "Five (Fives)"];
            end
            if(numVals(5) == 2)
                results = [results, "Stars (Sixes)"];
            end
            if(numVals(6) == 2 || only == true)
                results = [results, "Ten (Tens)"];
            end
        end
        
        function d = FSSelectToNum(app)
            if(app.FlamingSunDropDown.Value == "Halfmoons (Twos)")
                d = 1;
            end
            if(app.FlamingSunDropDown.Value == "Pyramids (Threes)")
                d = 2;
            end
            if(app.FlamingSunDropDown.Value == "Starburst (Fours)")
                d = 3;
            end
            if(app.FlamingSunDropDown.Value == "Five (Fives)")
                d = 4;
            end
            if(app.FlamingSunDropDown.Value == "Stars (Sixes)")
                d = 5;
            end
            if(app.FlamingSunDropDown.Value == "Ten (Tens)")
                d = 6;
            end
        end
        
        function timer = getUpdateTimer(app,playerNum)
            c = clock;
            disp("Minutes and then Seconds")
            disp(c(5))
            disp(c(6));
            timer = playerNum;
        end
        
        function getWin(app)
            for i = 1:2
                if(app.scores(i) >= 500)
                    uialert(app.UIFigure,"Player " + i + " has reached 500 points and won the game! Congratulations!", "Game has ended", "Icon","success","CloseFcn",app.UIFigureCloseRequest)
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.currentTick = 0;
            
            app.multMode;
            if(app.isLocal == true)
                app.currentPlayer = 1;

            else
                app.currentPlayer = app.playerNum;
                app.RollthediceButton_2.FontSize = 16;
                app.RollthediceButton_2.Text = "Other player must roll from their own computer!";
            end
            app.tickTimer = timer( 'ExecutionMode', 'fixedSpacing', 'Period', 1, 'TimerFcn', @(~,~) repeatFunc(app));
            start(app.tickTimer);
            
            app.TurnLabel.Text = "Your Turn";


            %{
            app.currentPlayer=1;
            app.rollDice
            app.currentPlayer=2;
            app.rollDice
            %}

        end

        % Button pushed function: RollthediceButton
        function RollthediceButtonPushed(app, event)
            
            app.rollDice;
            %{
            if(app.LockValueButton.Value == false)
                app.dvals(1) = randi(6);
            end
            if(app.LockValueButton_2.Value == false)
                app.dvals(2) = randi(6);
            end
            if(app.LockValueButton_3.Value == false)
                app.dvals(3) = randi(6);
            end
            if(app.LockValueButton_4.Value == false)
                app.dvals(4) = randi(6);
            end
            if(app.LockValueButton_5.Value == false)
                app.dvals(5) = randi(6);
                if(app.dvals(5) == 3)
                    app.dvals(5) = 6.5;
                end
            end
            app.Gauge.Value = app.dvals(1);
            app.Gauge_2.Value = app.dvals(2);
            app.Gauge_3.Value = app.dvals(3);
            app.Gauge_4.Value = app.dvals(4);
            app.Gauge_5.Value = app.dvals(5); % instead of a 3 it has a wild
            
            app.updateDiceImages();
            app.calcScore();
            
            app.currentPlayerIsYou = ~app.currentPlayerIsYou;
            if app.currentPlayerIsYou
                app.TurnLabel.Text = "Your Turn";
            else
                app.TurnLabel.Text = "Opponent's Turn";
            %}
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.isLocal == false)
                thingSpeakWrite(app.channelID, 'Fields', 8, 'Values', 0, 'WriteKey', app.writeAPIKey);
            end
            delete(app);
            delete(app.tickTimer);
        end

        % Button pushed function: RollthediceButton_2
        function RollthediceButton_2Pushed(app, event)
            if(app.isLocal == true)
                app.rollDice;
            end
        end

        % Value changed function: FlamingSunDropDown
        function FlamingSunDropDownValueChanged(app, event)
            if(app.FlamingSunDropDown.Value ~= "No Selection")
                app.waitForFSSelect = false;
            end
        end

        % Button pushed function: EndyourTurnButton
        function EndyourTurnButtonPushed(app, event)
                folder = fullfile(fileparts(mfilename('fullpath')), "Sounds");
                filename = fullfile(folder, "button.ogg");
                [y, Fs] = audioread(filename);
                player = audioplayer(y, Fs);
                
                filename = fullfile(folder, "button2.ogg");
                [y, Fs] = audioread(filename);
                player2 = audioplayer(y, Fs);
                
                play(player);
                play(player2);
            
            app.updateScoresLocal(app.calcScore + app.scores(app.currentPlayer));
            app.completedFirstRoll = false;
            app.calcScore;
            app.currentPlayer = 3 - app.currentPlayer;
            app.rollDiceButtonVis;

            if ~app.isLocal
                diceRolled = app.dvals((app.currentPlayer - 1) * 5 +1 : (app.currentPlayer - 1)*5+1);
                score = app.calcScore;
                accumulatedScore = app.scores(app.currentPlayer);
                event = 0;

                WriteGameState(diceRolled, app.currentPlayer, app.currentTurn, ...
                    score, accumulatedScore, event, 0);
            
            end

            app.currentTurn = app.currentTurn + 1;

            
            app.LockValueButton.Value = 0;
            app.LockValueButton_2.Value = 0;
            app.LockValueButton_3.Value = 0;
            app.LockValueButton_4.Value = 0;
            app.LockValueButton_5.Value = 0;
            app.LockValueButton_6.Value = 0;
            app.LockValueButton_7.Value = 0;
            app.LockValueButton_8.Value = 0;
            app.LockValueButton_9.Value = 0;
            app.LockValueButton_10.Value = 0;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LockValueButton = uibutton(app.UIFigure, 'state');
            app.LockValueButton.Text = 'Lock Value';
            app.LockValueButton_2 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_2.Text = 'Lock Value';
            app.LockValueButton_3 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_3.Text = 'Lock Value';
            app.LockValueButton_4 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_4.Text = 'Lock Value';
            app.LockValueButton_5 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_5.Text = 'Lock Value';
            app.RollthediceButton = uibutton(app.UIFigure, 'push');
            app.RollthediceButton.ButtonPushedFcn = createCallbackFcn(app, @RollthediceButtonPushed, true);
            app.RollthediceButton.Text = 'Roll the dice!';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [1 6];
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = 'Gauge';
            app.Gauge_2 = uigauge(app.UIFigure, 'circular');
            app.Gauge_2.Limits = [1 6];
            app.Gauge_3Label = uilabel(app.UIFigure);
            app.Gauge_3Label.Text = 'Gauge';
            app.Gauge_3 = uigauge(app.UIFigure, 'circular');
            app.Gauge_3.Limits = [1 6];
            app.Gauge_4Label = uilabel(app.UIFigure);
            app.Gauge_4Label.Text = 'Gauge';
            app.Gauge_4 = uigauge(app.UIFigure, 'circular');
            app.Gauge_4.Limits = [1 6];
            app.Gauge_5Label = uilabel(app.UIFigure);
            app.Gauge_5Label.Text = 'Gauge';
            app.Gauge_5 = uigauge(app.UIFigure, 'circular');
            app.Gauge_5.Limits = [1 7];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_10 = uiimage(app.UIFigure);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.YourOpponentsDiceLabel = uilabel(app.UIFigure);
            app.YourOpponentsDiceLabel.Text = 'Your Opponent''s Dice';
            app.YourScoreGaugeLabel = uilabel(app.UIFigure);
            app.YourScoreGaugeLabel.Text = 'Your Score';
            app.YourScoreGauge = uigauge(app.UIFigure, 'linear');
            app.YourScoreGauge.Limits = [0 500];
            app.OpponentsScoreGaugeLabel = uilabel(app.UIFigure);
            app.OpponentsScoreGaugeLabel.Text = 'Opponent''s Score';
            app.OpponentsScoreGauge = uigauge(app.UIFigure, 'linear');
            app.OpponentsScoreGauge.Limits = [0 500];
            app.TurnLabel = uilabel(app.UIFigure);
            app.TurnLabel.Text = 'Your turn';
            app.FlamingSunDropDownLabel = uilabel(app.UIFigure);
            app.FlamingSunDropDownLabel.Visible = 'off';
            app.FlamingSunDropDownLabel.Text = 'Flaming Sun';
            app.FlamingSunDropDown = uidropdown(app.UIFigure);
            app.FlamingSunDropDown.Items = {'None', 'Moons', 'Triangles', 'Bolts', '5', 'Stars', '10'};
            app.FlamingSunDropDown.ValueChangedFcn = createCallbackFcn(app, @FlamingSunDropDownValueChanged, true);
            app.FlamingSunDropDown.Visible = 'off';
            app.FlamingSunDropDown.Value = 'None';
            app.MultiplayerModeLabel = uilabel(app.UIFigure);
            app.MultiplayerModeLabel.Text = 'Multiplayer Mode:';
            app.TurnGauge = uigauge(app.UIFigure, 'semicircular');
            app.TurnGauge.Limits = [0.7 2.3];
            app.TurnGauge.MajorTicks = [0 1 2 3];
            app.TurnGauge.MajorTickLabels = {' ', ' Your Turn', ' Their Turn', '  '};
            app.TurnGauge.ScaleDirection = 'counterclockwise';
            app.TurnGauge.MinorTicks = [0.7 0.7375 0.775 0.8125 0.85 0.8875 0.925 0.9625 1 1.0375 1.075 1.1125 1.15 1.1875 1.225 1.2625 1.3 1.3375 1.375 1.4125 1.45 1.4875 1.525 1.5625 1.6 1.6375 1.675 1.7125 1.75 1.7875 1.825 1.8625 1.9 1.9375 1.975 2.0125 2.05 2.0875 2.125 2.1625 2.2 2.2375 2.275 2.3];
            app.TurnGauge.Value = 1;
            app.YourDiceLabel = uilabel(app.UIFigure);
            app.YourDiceLabel.Text = 'Your Dice';
            app.RollthediceButton_2 = uibutton(app.UIFigure, 'push');
            app.RollthediceButton_2.ButtonPushedFcn = createCallbackFcn(app, @RollthediceButton_2Pushed, true);
            app.RollthediceButton_2.Text = 'Roll the dice!';
            app.FetchingUpdatefromThingspeakinLabel = uilabel(app.UIFigure);
            app.FetchingUpdatefromThingspeakinLabel.Visible = 'off';
            app.FetchingUpdatefromThingspeakinLabel.Text = 'Fetching Update from Thingspeak in:';
            app.EndyourTurnButton = uibutton(app.UIFigure, 'push');
            app.EndyourTurnButton.ButtonPushedFcn = createCallbackFcn(app, @EndyourTurnButtonPushed, true);
            app.EndyourTurnButton.Text = 'End your Turn:';
            app.LockValueButton_6 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_6.Text = 'Lock Value';
            app.LockValueButton_7 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_7.Text = 'Lock Value';
            app.LockValueButton_8 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_8.Text = 'Lock Value';
            app.LockValueButton_9 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_9.Text = 'Lock Value';
            app.LockValueButton_10 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_10.Text = 'Lock Value';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end